classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        ImageAxes                       matlab.ui.control.UIAxes
        LoadButton                      matlab.ui.control.Button
        ImageAxes_2                     matlab.ui.control.UIAxes
        PleaseloadtheimageLabel         matlab.ui.control.Label
        thatyouwanttorecognizeLabel     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        PhotosRecognitionApplicationLabel  matlab.ui.control.Label
        ThisapplicationistraineonLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Classifier
        Net
        LabelsToPhotos
    end
    
    methods (Access = private)
        
        function displayLoadedImage(app,imagefile)
            
            
            try
                im = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Load Error');
                return;
            end
            
            %Display Loaded Image
            imagesc(app.ImageAxes,im);
            app.Label.Text = "";
            app.Label_2.Text = "";
            load("rimg", "rimg");
            imagesc(app.ImageAxes_2, rimg);
            drawnow
            predictImage(app, im);
            
        end
        
        
        function predictImage(app, im)
        
            imageSize = [224,224,3];
            
            featureLayer = "fc1000";
            newImage = im;
            ds = augmentedImageDatastore(imageSize, newImage, ...
                'ColorPreprocessing',"gray2rgb");
            imageFeatures = activations(app.Net, ds, featureLayer, ...
                "MiniBatchSize", 32, "OutputAs", "columns");
            label = predict(app.Classifier, imageFeatures, "ObservationsIn", "columns");
            label = char(label);
            
            app.Label.Text = "The person in the image is";
            app.Label_2.Text = label;
            
            im2 = app.LabelsToPhotos(label);
            
            imagesc(app.ImageAxes_2,im2);


        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Configure image axes
            app.ImageAxes_2.Visible = 'off';
            app.ImageAxes_2.Colormap = gray(256);
            axis(app.ImageAxes_2, 'image');
             
            load('classifier.mat','classifier');
            load('net.mat','net');
            load('labelsToPhotos.mat','labelsToPhotos');
            
            app.Classifier = classifier;
            app.Net = net;
            app.LabelsToPhotos = labelsToPhotos;
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               displayLoadedImage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Photos Recognition Application';
            app.Panel = uipanel(app.UIFigure);
            app.ImageAxes = uiaxes(app.Panel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Image';
            app.ImageAxes_2 = uiaxes(app.Panel);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.PleaseloadtheimageLabel = uilabel(app.Panel);
            app.PleaseloadtheimageLabel.Text = 'Please load the image ';
            app.thatyouwanttorecognizeLabel = uilabel(app.Panel);
            app.thatyouwanttorecognizeLabel.Text = 'that you want to recognize';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.PhotosRecognitionApplicationLabel = uilabel(app.UIFigure);
            app.PhotosRecognitionApplicationLabel.Text = 'Photos Recognition Application';
            app.ThisapplicationistraineonLabel = uilabel(app.UIFigure);
            app.ThisapplicationistraineonLabel.Text = 'This application is trained on a convolutional neural network that is 50 layers deep.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end